classdef project_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HANDWRITTENTEXTRECOGNITIONLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        CONVERTFILETab_2                matlab.ui.container.Tab
        INSERTFILEButton                matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        TextOutputTextAreaLabel         matlab.ui.control.Label
        TextOutputTextArea              matlab.ui.control.TextArea
        Panel                           matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        GETOUTPUTButton                 matlab.ui.control.Button
        INFORMATIONTab                  matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        EXITButton_2                    matlab.ui.control.Button
        PUNEINSTITUTEOFCOMPUTERTECHNOLOGYPUNE43Label  matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        MadhulikaGaikwadB150053071Label  matlab.ui.control.Label
        EXITButton                      matlab.ui.control.Button
        ShitalHasnalkarB150053093Label  matlab.ui.control.Label
        PrajaktaPalkarB150053179Label   matlab.ui.control.Label
        GUIDEDrAPDhandeLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        ipimg; % Description
        getnum;
        getimgnum;
    end
    
    methods (Access = private)
        
        function confirmClose(app, ~, event)
            answer = event.SelectedOption;
            
            %close app if user clicks OK
            if strcmp(answer,'OK')
                delete(app);
            end
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imshow('E:\BE_PROJECT\app\write1.png','Parent',app.UIAxes);
            imshow('E:\BE_PROJECT\app\file.jfif','Parent',app.UIAxes2);
            imshow('E:\BE_PROJECT\app\pict.jfif','Parent',app.UIAxes3);
            app.GETOUTPUTButton.Enable = 'off';
            app.TextOutputTextArea.Visible = 'off';
        end

        % Button pushed function: INSERTFILEButton
        function INSERTFILEButtonPushed(app, event)
            
            [filename, pathname] = uigetfile({'*.png';'*jpg';'*bmp';},'Choose Input File');
            
            fullpath = strcat(pathname,filename);
            
            app.ipimg = imread(fullpath);
            app.GETOUTPUTButton.Enable = 'on';
            
            
        I= app.ipimg;  
           figure;imshow(I);
            I=rgb2gray(I);  
lap = [1 1 1; 1 -8 1; 1 1 1];    %laplacian filter
resp = uint8(filter2(lap, I, 'same'));
I= imsubtract(I, resp);
I = imadjust(I);

%Convert to binary image and calculate global threshold
threshold = graythresh(I);
I=~imbinarize(I,threshold);
I=imresize(I,[1000 1000]);

% Remove all object containing fewer than 30 pixels
 I = bwareaopen(I,10);
 pause(1);
% figure;imshow(I);
 
%calculate horizontal and vertical profile of the image
 horizontalProjection = mean(I, 2);
verticalProjection = mean(I, 1);
%  figure; barh( horizontalProjection);title('horizontal profile of image');
 
 %segment the lines from image
 nonzero=zeros(1,1000);
 horizontalProjection=horizontalProjection.*100;
for i=1:1000
if horizontalProjection(i,1)<1.8
    nonzero(1,i)=0;  
else
     nonzero(1,i)=1;
end
end
j=1;
for i=1:999
if (nonzero(1,i+1) ~= 0)&&(nonzero(1,i) == 0)
    arrzero(1,j)=i+1;  
    j=j+1;
end
end
j=1;
for i=1:999
if (nonzero(1,i+1) == 0)&&(nonzero(1,i) ~= 0)
    arrzero1(1,j)=i;  
    j=j+1;
end
end

% figure;
%for loop for line segmentation
for i=1:length(arrzero1)
   I1=I((arrzero(i):arrzero1(i)),:); 
   I1=imresize(I1,[1000 1000]);
%   subplot(5,1,i);imshow(I1);
%    baseFileName = sprintf('image %d.png',i);
% %save individual words into specific folder
% fullFileName = fullfile('E:\BE_PROJECT\wordscollect', baseFileName);
% imwrite( I1, fullFileName);

%vertical projection of line
verticalProjection1 = mean(I1, 1);
% figure; bar(verticalProjection1);

%segment words from line
nonzero1=zeros(1,1000);
verticalProjection1=verticalProjection1.*100;
for k=1:1000
if verticalProjection1(1,k)<0.5
    nonzero1(1,k)=0;  
else
     nonzero1(1,k)=1;
end
end

j=1;
for k=1:999
if (nonzero1(1,k+1) ~= 0)&&(nonzero1(1,k) == 0)
    arrzero10(1,j)=k+1;  
    j=j+1;
end
end

h=1;
for k=1:999
if (nonzero1(1,k+1) == 0)&&(nonzero1(1,k) == 1)
    arrzero11(1,h)=k;  
    h=h+1;
end
end

%for loop for word segmentation
% figure;
for c=1:length(arrzero11)
%    figure;
   I2=I1(:,(arrzero10(c):arrzero11(c)));
   I2=imresize(I2,[1000 1000]);
   I2=~I2;
%   subplot(1,6,p);imshow(I2);
    baseFileName = sprintf('image_%d%d.jpg',i,c);

%save individual words into specific folder
fullFileName = fullfile('E:\BE_PROJECT\wordscollect', baseFileName);
imwrite( I2, fullFileName);


%%letter segment

end     %loop end for word segment
  
app.getnum=length(arrzero1);
app.getimgnum(i)=length(arrzero11);
%  I1=I((arrzero(1):arrzero1(1)),:); 
%  figure;imshow(I1);

end %loop end for line segment


        end

        % Button pushed function: GETOUTPUTButton
        function GETOUTPUTButtonPushed(app, event)
         
            app.Panel.Visible = 'off';
              app.GETOUTPUTButton.Visible = 'off';
               app.TextOutputTextArea.Visible = 'on';
            strnew ='';
            strblank=' ';
             load ('E:\BE_PROJECT\trained_net1.mat');
%              getnum=3;
%              getimgnum=[5 6 6 ];
        for m=1:app.getnum
     for n=1:app.getimgnum(m)
        
         baseFileName = sprintf('image_%d%d.jpg',m,n);

fullFileName = fullfile('E:\BE_PROJECT\wordscollect', baseFileName);
I1=imread( fullFileName);       
               
               
% I1=app.ipimg;
%   I1=imread('E:\BE_PROJECT\wordscollect\image_12.jpg'); 
% figure;imshow(I1);
% I=~I1;
% figure;  imshow(I);
%   I = rgb2gray(I1);
lap = [1 1 1; 1 -8 1; 1 1 1];
resp = uint8(filter2(lap, I1, 'same'));
I= imsubtract(I1, resp);
J = imadjust(I);
threshold = graythresh(J);
J=imbinarize(J,threshold);

 J = bwareaopen(J,10);
 pause(1);
 p=zeros(1,750);


 J=imresize(I1,[750,750]);
%    figure;  imshow(J);   

  [r1, c1]=size(J);
 for i=1:r1
     count=0;
     for j=1:c1
     if J(i,j)==0
         count=count+1;
     end
     end
 y=0.7*c1;
     if count >= y
         p(1,i)=i;
         J(i,:)=1;
     end    
 
 end
 for k=1:15
J= medfilt2(J);
 end
% J=imresize(J,[300,300]);
 maxnum=max(p(1,:));
  minnum=min(p(p(1,:)>0));
% imshow(I); title('Original image');
%  figure;  imshow(J); 
J1=J(1:minnum-70,:);
J2=J(maxnum+70:750,:);
newImg = cat(1,J1,J2);

 newImg=imresize(newImg,[750,750]);
 threshold1 = graythresh( newImg);

 newImg = imbinarize(newImg, threshold1);
  for k=1:15
 newImg= medfilt2(newImg);
  end
%   figure;imshow(newImg);


 savearray=zeros(1,750);
 for j=1:750
     sum=0;
     for i=1:750
          sum=sum+newImg(i,j);
         if sum == 750
         savearray(1,j)=1;
         else
         savearray(1,j)=0;  
         end
     end
%       savearray(1,j)=sum;  
 end     
 
j=1;
for i=5:749
if (savearray(1,i+1) ~= 0)&&(savearray(1,i) == 0)
    array_01(1,j)=i+1;  
    j=j+1;
end
end

 
j=1;
for i=5:749
if (savearray(1,i+1) == 0)&&(savearray(1,i) ~= 0)
    array_10(1,j)=i+1;  
    j=j+1;
end
end
str='';
x=length(array_01);
%  figure;
for p=1:x
   bwPic= newImg(:,array_10(1,p):array_01(1,p));
    bwPic=imresize(bwPic,[227,227]);
 
%     subplot(1,x,p);imshow(bwPic); 
   

    baseFileName = sprintf('image_%d.jpg',p);

%save individual words into specific folder
fullFileName = fullfile('E:\BE_PROJECT', baseFileName);
imwrite(bwPic , fullFileName);

bwPicSize = size(bwPic);
rgbPic = zeros(bwPicSize(1),bwPicSize(2),3);
rgbPic(bwPic==1)=255;
rgbPic(:,:,2) = rgbPic(:,:,1);
rgbPic(:,:,3) = rgbPic(:,:,1);
rgbPic = im2uint8(rgbPic);

testpreds2 = classify(newnet,rgbPic ); 

testpreds2=char(testpreds2);
disp(testpreds2);
str=[str testpreds2];
str=char(str);


end    

strnew=[strnew str]; 
 strnew=[strnew strblank];
  clear array_01;
   clear array_10;
     end
%      strnew=[strnew strblank];
      clear array_01;
   clear array_10;
        end
        
       app.TextOutputTextArea.Value= strnew;
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            msg = 'Are you sure you want to Exit?';
            uiconfirm(app.UIFigure,msg,'Confirm Close','CloseFcn',@app.confirmClose);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.HANDWRITTENTEXTRECOGNITIONLabel = uilabel(app.UIFigure);
            app.HANDWRITTENTEXTRECOGNITIONLabel.Text = 'HANDWRITTEN TEXT RECOGNITION ';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CONVERTFILETab_2 = uitab(app.TabGroup);
            app.CONVERTFILETab_2.Title = 'CONVERT FILE';
            app.INSERTFILEButton = uibutton(app.CONVERTFILETab_2, 'push');
            app.INSERTFILEButton.ButtonPushedFcn = createCallbackFcn(app, @INSERTFILEButtonPushed, true);
            app.INSERTFILEButton.Text = 'INSERT FILE';
            app.UIAxes = uiaxes(app.CONVERTFILETab_2);
            app.TextOutputTextAreaLabel = uilabel(app.CONVERTFILETab_2);
            app.TextOutputTextAreaLabel.Text = 'Text Output';
            app.TextOutputTextArea = uitextarea(app.CONVERTFILETab_2);
            app.Panel = uipanel(app.CONVERTFILETab_2);
            app.UIAxes2 = uiaxes(app.Panel);
            app.GETOUTPUTButton = uibutton(app.Panel, 'push');
            app.GETOUTPUTButton.ButtonPushedFcn = createCallbackFcn(app, @GETOUTPUTButtonPushed, true);
            app.GETOUTPUTButton.Text = 'GET OUTPUT';
            app.INFORMATIONTab = uitab(app.TabGroup);
            app.INFORMATIONTab.Title = 'INFORMATION';
            app.TextArea = uitextarea(app.INFORMATIONTab);
            app.TextArea.Value = {'-   This project is aimed at recognition of handwritten Marathi characters. This project is restricted to Marathi characters (Devnagri script) only.'; '-    Technical approach is'; '1. Image of the handwritten info which is to be converted into test file is preprocessed.'; '2. Information present in input image is divided into paragraphs. These paragraphs are further divided into sentences then to words and last into letters.'; '3. Those individual letters are recognized by training the ANN (i.e.CNN-Alexnet)(MATLAB Neural network toolbox).'; '4. After recognition individual letter is added into text file.'; '-The system meets the demand of converting scanned document into text file which can be edited as per the requirement. The system also provides editing with less hardware.'};
            app.EXITButton_2 = uibutton(app.INFORMATIONTab, 'push');
            app.EXITButton_2.Text = 'EXIT';
            app.PUNEINSTITUTEOFCOMPUTERTECHNOLOGYPUNE43Label = uilabel(app.UIFigure);
            app.PUNEINSTITUTEOFCOMPUTERTECHNOLOGYPUNE43Label.Text = 'PUNE INSTITUTE OF COMPUTER TECHNOLOGY ,PUNE 43';
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.MadhulikaGaikwadB150053071Label = uilabel(app.UIFigure);
            app.MadhulikaGaikwadB150053071Label.Text = 'Madhulika Gaikwad  (B150053071)';
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.ShitalHasnalkarB150053093Label = uilabel(app.UIFigure);
            app.ShitalHasnalkarB150053093Label.Text = 'Shital Hasnalkar (B150053093)';
            app.PrajaktaPalkarB150053179Label = uilabel(app.UIFigure);
            app.PrajaktaPalkarB150053179Label.Text = 'Prajakta Palkar (B150053179)';
            app.GUIDEDrAPDhandeLabel = uilabel(app.UIFigure);
            app.GUIDEDrAPDhandeLabel.Text = 'GUIDE :Dr.A.P.Dhande';
        end
    end

    methods (Access = public)

        % Construct app
        function app = project_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end